classdef ASK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        TransmitButton             matlab.ui.control.StateButton
        Image                      matlab.ui.control.Image
        CARRIERButton              matlab.ui.control.StateButton
        MESSAGEButton              matlab.ui.control.StateButton
        DEMODULATIONButton         matlab.ui.control.StateButton
        MODULATIONButton           matlab.ui.control.StateButton
        CARRIERGENERATIONPanel     matlab.ui.container.Panel
        CarrierFrequencyKnob       matlab.ui.control.Knob
        CarrierFrequencyKnobLabel  matlab.ui.control.Label
        AmplitudeSlider_2          matlab.ui.control.Slider
        AmplitudeSlider_2Label     matlab.ui.control.Label
        MESSAGEGENERATIONPanel     matlab.ui.container.Panel
        FrequencyKnob              matlab.ui.control.Knob
        FrequencyKnobLabel         matlab.ui.control.Label
        AmplitudeSlider            matlab.ui.control.Slider
        AmplitudeSliderLabel       matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            fp = app.FrequencyKnob.Value;
            
        end

        % Value changed function: CarrierFrequencyKnob
        function CarrierFrequencyKnobValueChanged(app, event)
           fc = app.CarrierFrequencyKnob.Value;
            
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            amp = app.AmplitudeSlider.Value;
            
        end

        % Value changed function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanged(app, event)
            amc = app.AmplitudeSlider_2.Value;
            
        end

        % Callback function
        function GENERATEButtonPushed(app, event)
  
t = 0:0.001:1;
c = app.AmplitudeSlider_2.Value * sin(2*pi*app.CarrierFrequencyKnob.Value * t);
m = app.AmplitudeSlider.Value/2 * square(2*pi*app.FrequencyKnob.Value * t) + (app.AmplitudeSlider.Value/2);
plot(app.UIAxes2, t, m);
plot(app.UIAxes, t, c);

            
        end

        % Value changed function: MODULATIONButton
        function MODULATIONButtonValueChanged(app, event)
            value = app.MODULATIONButton.Value;
              
 t = 0:0.001:1;
c = app.AmplitudeSlider_2.Value * sin(2*pi*app.CarrierFrequencyKnob.Value * t);
m = app.AmplitudeSlider.Value/2 * square(2*pi*app.FrequencyKnob.Value * t) + (app.AmplitudeSlider.Value/2);
w = c .* m;
plot(app.UIAxes2, t, w);


        end

        % Value changed function: DEMODULATIONButton
        function DEMODULATIONButtonValueChanged(app, event)
            value = app.DEMODULATIONButton.Value;
t = 0:0.001:1;
c = app.AmplitudeSlider_2.Value * sin(2*pi*app.CarrierFrequencyKnob.Value * t);
m = app.AmplitudeSlider.Value/2 * square(2*pi*app.FrequencyKnob.Value * t) + (app.AmplitudeSlider.Value/2);
w = c .* m;
demodulatedSignal = w .* c;  
filteredSignal = myLowPassFilter(demodulatedSignal, app.FrequencyKnob.Value/(2*pi));

% Thresholding to recover the message signal
recoveredMessage = zeros(size(m));
threshold = max(filteredSignal) / 2;
for i = 1:length(filteredSignal)
    if filteredSignal(i) > threshold
        recoveredMessage(i) = app.AmplitudeSlider.Value;
    end
end

plot(app.UIAxes2,t,recoveredMessage);
        end

        % Value changed function: MESSAGEButton
        function MESSAGEButtonValueChanged(app, event)
            value = app.MESSAGEButton.Value;
            
            
t = 0:0.001:1;

m = app.AmplitudeSlider.Value/2 * square(2*pi*app.FrequencyKnob.Value * t) + (app.AmplitudeSlider.Value/2);
plot(app.UIAxes2, t, m);


        end

        % Value changed function: CARRIERButton
        function CARRIERButtonValueChanged(app, event)
            value = app.CARRIERButton.Value;
            t = 0:0.001:1;
c = app.AmplitudeSlider_2.Value * sin(2*pi*app.CarrierFrequencyKnob.Value * t);

plot(app.UIAxes2, t, c);

        end

        % Value changed function: TransmitButton
        function TransmitButtonValueChanged(app, event)
value = app.TransmitButton.Value;


t = 0:0.001:1;
c = app.AmplitudeSlider_2.Value * sin(2*pi*app.CarrierFrequencyKnob.Value * t);
m = app.AmplitudeSlider.Value/2 * square(2*pi*app.FrequencyKnob.Value * t) + (app.AmplitudeSlider.Value/2);
w = c .* m;
demodulatedSignal = w .* c;  
filteredSignal = myLowPassFilter(demodulatedSignal, app.FrequencyKnob.Value/(2*pi));

% Thresholding to recover the message signal
recoveredMessage = zeros(size(m));
threshold = max(filteredSignal) / 2;
for i = 1:length(filteredSignal)
    if filteredSignal(i) > threshold
        recoveredMessage(i) = app.AmplitudeSlider.Value;
    end
end

transferredArray = [];

% Transfer non-zero values from recoveredMessage to transferredArray
for i = 1:length(recoveredMessage)
    if recoveredMessage(i) ~= 0
        transferredArray(end+1) = recoveredMessage(i);
    end
end

a = arduino();
ledPin = 'D9';
configurePin(a, ledPin, 'DigitalOutput');
writeDigitalPin(a, ledPin, 0);

duration = 30; % Duration in seconds

for i = 1:duration
    if transferredArray(i) == 0
        writeDigitalPin(a, ledPin, 0);
    else
        writeDigitalPin(a, ledPin, 1);
    end
    pause(1); % Delay for 1 second
end

writeDigitalPin(a, ledPin, 0); % Turn off the LED after the desired duration

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'DISPLAY')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MESSAGEGENERATIONPanel = uipanel(app.UIFigure);
            app.MESSAGEGENERATIONPanel.Title = 'MESSAGE GENERATION';
            app.AmplitudeSliderLabel = uilabel(app.MESSAGEGENERATIONPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.MESSAGEGENERATIONPanel);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.MESSAGEGENERATIONPanel);
            app.FrequencyKnobLabel.Text = ' Frequency';
            app.FrequencyKnob = uiknob(app.MESSAGEGENERATIONPanel, 'continuous');
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.CARRIERGENERATIONPanel = uipanel(app.UIFigure);
            app.CARRIERGENERATIONPanel.Title = 'CARRIER GENERATION';
            app.AmplitudeSlider_2Label = uilabel(app.CARRIERGENERATIONPanel);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.CARRIERGENERATIONPanel);
            app.AmplitudeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanged, true);
            app.CarrierFrequencyKnobLabel = uilabel(app.CARRIERGENERATIONPanel);
            app.CarrierFrequencyKnobLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyKnob = uiknob(app.CARRIERGENERATIONPanel, 'continuous');
            app.CarrierFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyKnobValueChanged, true);
            app.MODULATIONButton = uibutton(app.UIFigure, 'state');
            app.MODULATIONButton.ValueChangedFcn = createCallbackFcn(app, @MODULATIONButtonValueChanged, true);
            app.MODULATIONButton.Text = 'MODULATION';
            app.DEMODULATIONButton = uibutton(app.UIFigure, 'state');
            app.DEMODULATIONButton.ValueChangedFcn = createCallbackFcn(app, @DEMODULATIONButtonValueChanged, true);
            app.DEMODULATIONButton.Text = 'DEMODULATION';
            app.MESSAGEButton = uibutton(app.UIFigure, 'state');
            app.MESSAGEButton.ValueChangedFcn = createCallbackFcn(app, @MESSAGEButtonValueChanged, true);
            app.MESSAGEButton.Text = 'MESSAGE';
            app.CARRIERButton = uibutton(app.UIFigure, 'state');
            app.CARRIERButton.ValueChangedFcn = createCallbackFcn(app, @CARRIERButtonValueChanged, true);
            app.CARRIERButton.Text = 'CARRIER';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '24097-4-antenna-hd.png';
            app.TransmitButton = uibutton(app.UIFigure, 'state');
            app.TransmitButton.ValueChangedFcn = createCallbackFcn(app, @TransmitButtonValueChanged, true);
            app.TransmitButton.Text = 'Transmit';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'ASKLOGO2.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end